classdef plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        RangeSliderLabel    matlab.ui.control.Label
        RangeSlider         matlab.ui.control.Slider
        WidthSliderLabel    matlab.ui.control.Label
        WidthSlider         matlab.ui.control.Slider
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes2_2           matlab.ui.control.UIAxes
        WidthSlider_2       matlab.ui.control.Slider
        RangeSlider_2Label  matlab.ui.control.Label
        RangeSlider_2       matlab.ui.control.Slider
    end


    properties (Access = public)
            x_inter_1 = fread(fopen('./data/decoder_carl_test','rb'), 'float32');
            x_inter_1_source = fread(fopen('./data/source','rb'), 'float32');
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            value = app.RangeSlider.Value;
            width = app.WidthSlider.Value;
            
            plot(app.UIAxes_2, app.x_inter_1_source(value:2:value+width),app.x_inter_1_source(value+1:2:value+width+1));
            plot(app.UIAxes2_2, app.x_inter_1_source(value:2:value+width));
 
        end

        % Value changed function: WidthSlider
        function WidthSliderValueChanged(app, event)
            value = app.RangeSlider.Value;
            width = app.WidthSlider.Value;
            
            plot(app.UIAxes_2, app.x_inter_1_source(value:2:value+width),app.x_inter_1_source(value+1:2:value+width+1));
            plot(app.UIAxes2_2, app.x_inter_1_source(value:2:value+width));
 
        end

        % Value changed function: WidthSlider_2
        function WidthSlider_2ValueChanged(app, event)
            value_2 = app.RangeSlider_2.Value;
            width_2 = app.WidthSlider_2.Value;
            
            plot(app.UIAxes, app.x_inter_1(value_2:2:value_2+width_2),app.x_inter_1(value_2+1:2:value_2+width_2+1));
            plot(app.UIAxes2, app.x_inter_1(value_2:2:value_2+width_2));
            
        end

        % Value changed function: RangeSlider_2
        function RangeSlider_2ValueChanged(app, event)
            value_2 = app.RangeSlider_2.Value;
            width_2 = app.WidthSlider_2.Value;
            
            plot(app.UIAxes, app.x_inter_1(value_2:2:value_2+width_2),app.x_inter_1(value_2+1:2:value_2+width_2+1));
            plot(app.UIAxes2, app.x_inter_1(value_2:2:value_2+width_2));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IQ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RangeSliderLabel = uilabel(app.UIFigure);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.UIFigure);
            app.RangeSlider.Limits = [122000 155000];
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Value = 122000;
            app.WidthSliderLabel = uilabel(app.UIFigure);
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.UIFigure);
            app.WidthSlider.Limits = [100 33000];
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.Value = 33000;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Raw')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'IQ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Raw')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.WidthSlider_2 = uislider(app.UIFigure);
            app.WidthSlider_2.Limits = [100 33000];
            app.WidthSlider_2.ValueChangedFcn = createCallbackFcn(app, @WidthSlider_2ValueChanged, true);
            app.WidthSlider_2.Value = 33000;
            app.RangeSlider_2Label = uilabel(app.UIFigure);
            app.RangeSlider_2Label.Text = 'Range';
            app.RangeSlider_2 = uislider(app.UIFigure);
            app.RangeSlider_2.Limits = [0 25000];
            app.RangeSlider_2.ValueChangedFcn = createCallbackFcn(app, @RangeSlider_2ValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = plotter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end